classdef GUI101 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CFileDropDown          matlab.ui.control.DropDown
        CFileDropDownLabel     matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        TextAreaLabel          matlab.ui.control.Label
        STARTButton            matlab.ui.control.Button
        HzEditField            matlab.ui.control.NumericEditField
        HzEditFieldLabel       matlab.ui.control.Label
        AngleEditField         matlab.ui.control.NumericEditField
        AngleEditFieldLabel    matlab.ui.control.Label
        CommandsDropDown       matlab.ui.control.DropDown
        CommandsDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        MotorCheckBoxes matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            numMotors = 8;


            % Create a panel to hold them
            motorPanel = uipanel(app.UIFigure);
            motorPanel.Title = 'Select Motors';
            motorPanel.Position = [50, 300, 150, 300];  % Adjust as needed

            for i = 1:numMotors
                cb = uicheckbox(motorPanel);
                cb.Text = sprintf('Motor %d', i-1);
                cb.Position = [10, 280 - i*20, 100, 20];  % Stack vertically
                app.MotorCheckBoxes(i) = cb;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            selectedChannels = [];
            for i = 1:length(app.MotorCheckBoxes)
                if app.MotorCheckBoxes(i).Value
                    selectedChannels(end+1) = i - 1;  % 0-based motor IDs
                end
            end
            cmd = app.CommandsDropDown.Value;
            angle = app.AngleEditField.Value;
            hz = app.HzEditField.Value;
            fn = app.CFileDropDown.Value;
            try
                switch cmd
                    case 'RUN'
                        feval(fn, cmd, selectedChannels, hz);
                    case 'POSITsION'
                        feval(fn, cmd, selectedChannels, angle)
                    case 'FLAP'
                        feval(fn, cmd, selectedChannels, angle, hz);
                    case {'SETHOME', 'RESETHOME'}
                        feval(fn, cmd, selectedChannels, 0);
                end
                app.TextArea.Value = [app.TextArea.Value; sprintf("✓ Ran %s on channel %d", cmd, selectedChannels)];
            catch ME
                app.TextArea.Value = [app.TextArea.Value; sprintf("Error %s", ME.message)];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CommandsDropDownLabel = uilabel(app.UIFigure);
            app.CommandsDropDownLabel.Text = 'Commands';
            app.CommandsDropDown = uidropdown(app.UIFigure);
            app.CommandsDropDown.Items = {'RUN', 'POSITION', 'FLAP', 'SETHOME'};
            app.CommandsDropDown.Value = 'RUN';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.CFileDropDownLabel = uilabel(app.UIFigure);
            app.CFileDropDownLabel.Text = 'C++ File';
            app.CFileDropDown = uidropdown(app.UIFigure);
            app.CFileDropDown.Items = {'preliminaryFunctions', 'ClearView101', 'ATG102'};
            app.CFileDropDown.Value = 'preliminaryFunctions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI101

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end